classdef manipulateROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROImanipulationUIFigure         matlab.ui.Figure
        xlocationEditFieldLabel         matlab.ui.control.Label
        xlocationEditField              matlab.ui.control.NumericEditField
        ylocationEditFieldLabel         matlab.ui.control.Label
        ylocationEditField              matlab.ui.control.NumericEditField
        zlocationEditFieldLabel         matlab.ui.control.Label
        zlocationEditField              matlab.ui.control.NumericEditField
        rollPlusButton                  matlab.ui.control.Button
        rollMinusButton                 matlab.ui.control.Button
        pitchMinusButton                matlab.ui.control.Button
        pitchPlusButton                 matlab.ui.control.Button
        rotstepEditFieldLabel           matlab.ui.control.Label
        rotstepEditField                matlab.ui.control.NumericEditField
        xxPlusButton                    matlab.ui.control.Button
        xxMinusButton                   matlab.ui.control.Button
        yyPlusButton                    matlab.ui.control.Button
        yyMinusButton                   matlab.ui.control.Button
        zzPlusButton                    matlab.ui.control.Button
        zzMinusButton                   matlab.ui.control.Button
        movestepEditFieldLabel          matlab.ui.control.Label
        movestepEditField               matlab.ui.control.NumericEditField
        getobjectButton                 matlab.ui.control.Button
        xxsizeEditFieldLabel            matlab.ui.control.Label
        xxsizeEditField                 matlab.ui.control.NumericEditField
        yysizeEditFieldLabel            matlab.ui.control.Label
        yysizeEditField                 matlab.ui.control.NumericEditField
        zzsizeEditFieldLabel            matlab.ui.control.Label
        zzsizeEditField                 matlab.ui.control.NumericEditField
        yawMinusButton                  matlab.ui.control.Button
        yawPlusButton                   matlab.ui.control.Button
        executeaftermoveEditFieldLabel  matlab.ui.control.Label
        executeaftermoveEditField       matlab.ui.control.EditField
        objectnameEditFieldLabel        matlab.ui.control.Label
        objectnameEditField             matlab.ui.control.EditField
    end


    properties (Access = private)
        manipulatedObject % Description
    end

    methods (Access = private)
    
        function shiftObject(app,shiftAxis,shiftDistance)
            ax = shiftAxis;
            shift = shiftDistance;
            ax = ax(2,:) - ax(1,:); % calculate shift axis
            ax = ax/norm(ax); % make unit vector
            shift = ax * shift;
            numVerts = length(app.manipulatedObject.Vertices(:,1));
            app.manipulatedObject.Vertices = app.manipulatedObject.Vertices + repmat(shift,[numVerts, 1]);
            app.manipulatedObject.UserData.ROIxaxis = app.manipulatedObject.UserData.ROIxaxis + repmat(shift,[2, 1]);
            app.manipulatedObject.UserData.ROIyaxis = app.manipulatedObject.UserData.ROIyaxis + repmat(shift,[2, 1]);
            app.manipulatedObject.UserData.ROIzaxis = app.manipulatedObject.UserData.ROIzaxis + repmat(shift,[2, 1]);
            updateLocation(app);
        end
        
        function len = getVectorLength(app,Axis)
            Axis = Axis(2,:) - Axis(1,:);
            len = norm(Axis);
        end
        
        function scaleObject(app,scaleAxis,currentSize,targetSize)
            ax = scaleAxis;
            ax = ax(2,:) - ax(1,:);
            ax = ax/norm(ax); % make unit vector
            
            scaleFactor = targetSize/currentSize;
            origin = scaleAxis(1,:);
            
            %scale object
            verts = app.manipulatedObject.Vertices;
            numVerts = length(verts(:,1));
            verts = verts - repmat(origin,[numVerts, 1]); % transform into local coordinates
            dist = sum(verts.*repmat(ax,[numVerts,1]),2);% dot product with scale axis for projected length
            dist = dist * scaleFactor - dist;
            shift = repmat(ax,[numVerts,1]) .* repmat(dist,[1,3]); %base shift vector        
            app.manipulatedObject.Vertices = verts + shift + repmat(origin,[numVerts, 1]);
            
            %scale coordinate system
            vec = app.manipulatedObject.UserData.ROIxaxis(2,:) - origin;
            dist = sum(vec.*ax,2);
            dist = dist * scaleFactor - dist;
            shift = ax * dist;
            app.manipulatedObject.UserData.ROIxaxis(2,:) = vec + shift + origin;
            
            vec = app.manipulatedObject.UserData.ROIyaxis(2,:) - origin;
            dist = sum(vec.*ax,2);
            dist = dist * scaleFactor - dist;
            shift = ax * dist;
            app.manipulatedObject.UserData.ROIyaxis(2,:) = vec + shift + origin;
            
            vec = app.manipulatedObject.UserData.ROIzaxis(2,:) - origin;
            dist = sum(vec.*ax,2);
            dist = dist * scaleFactor - dist;
            shift = ax * dist;
            app.manipulatedObject.UserData.ROIzaxis(2,:) = vec + shift + origin;
            
        end
        
        function updateLocation(app)
            origin = app.manipulatedObject.UserData.ROIxaxis(1,:);
            
            app.xlocationEditField.Value = origin(1);
            app.ylocationEditField.Value = origin(2);
            app.zlocationEditField.Value = origin(3);
        end
        
        function moveToLocation(app,newLocation)% location as [x,y,z]
            shift = newLocation - app.manipulatedObject.UserData.ROIzaxis(1,:); % newLocation - origin
            numVerts = length(app.manipulatedObject.Vertices(:,1));
            
            app.manipulatedObject.Vertices = app.manipulatedObject.Vertices + repmat(shift,[numVerts, 1]);
            
            app.manipulatedObject.UserData.ROIxaxis = app.manipulatedObject.UserData.ROIxaxis + repmat(shift,[2, 1]);
            app.manipulatedObject.UserData.ROIyaxis = app.manipulatedObject.UserData.ROIyaxis + repmat(shift,[2, 1]);
            app.manipulatedObject.UserData.ROIzaxis = app.manipulatedObject.UserData.ROIzaxis + repmat(shift,[2, 1]);
        end
        
        function rotateObject(app,axisAngle)
            %transform object vertices into local coordinate system
            origin = app.manipulatedObject.UserData.ROIxaxis(1,:);
            verts = app.manipulatedObject.Vertices;
            numVerts = length(verts(:,1));            
            verts = verts - repmat(origin,[numVerts, 1]);      
            
            % transform coordinate system vectors
            x = app.manipulatedObject.UserData.ROIxaxis(2,:) - origin;
            y = app.manipulatedObject.UserData.ROIyaxis(2,:) - origin;
            z = app.manipulatedObject.UserData.ROIzaxis(2,:) - origin;
            
            %rotate about axis
            rotmat = vrrotvec2mat(axisAngle);
            verts = verts * rotmat;
            x = x * rotmat;
            y = y * rotmat;
            z = z * rotmat;
            
            %transform back into global coordinate system
            app.manipulatedObject.Vertices = verts + repmat(origin,[numVerts, 1]); 
            app.manipulatedObject.UserData.ROIxaxis(2,:) = x + origin;
            app.manipulatedObject.UserData.ROIyaxis(2,:) = y + origin;
            app.manipulatedObject.UserData.ROIzaxis(2,:) = z + origin;
        end
        
    end


    methods (Access = private)

        % Button pushed function: getobjectButton
        function getobjectButtonPushed(app, event)
            
            if isempty(app.objectnameEditField.Value)
                app.manipulatedObject = gco;
            else
                app.manipulatedObject = evalin('base', app.objectnameEditField.Value);
            end
            
            % calculate ROI size (from coordinate system)
            app.xxsizeEditField.Value = getVectorLength(app,app.manipulatedObject.UserData.ROIxaxis);
            app.yysizeEditField.Value = getVectorLength(app,app.manipulatedObject.UserData.ROIyaxis);
            app.zzsizeEditField.Value = getVectorLength(app,app.manipulatedObject.UserData.ROIzaxis);
            
            % get ROI location
            updateLocation(app);


        end

        % Button pushed function: xxPlusButton
        function xxPlusButtonPushed(app, event)
            shiftAxis = app.manipulatedObject.UserData.ROIxaxis; % get manipulation axis
            shiftDistance = app.movestepEditField.Value;
            shiftObject(app,shiftAxis,shiftDistance);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: zzMinusButton
        function zzMinusButtonPushed(app, event)
            shiftAxis = app.manipulatedObject.UserData.ROIzaxis; % get manipulation axis
            shiftDistance = uminus(app.movestepEditField.Value);
            shiftObject(app,shiftAxis,shiftDistance);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: zzPlusButton
        function zzPlusButtonPushed(app, event)
            shiftAxis = app.manipulatedObject.UserData.ROIzaxis; % get manipulation axis
            shiftDistance = app.movestepEditField.Value;
            shiftObject(app,shiftAxis,shiftDistance);
            evalin('base',app.executeaftermoveEditField.Value);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: yyPlusButton
        function yyPlusButtonPushed(app, event)
            shiftAxis = app.manipulatedObject.UserData.ROIyaxis; % get manipulation axis
            shiftDistance = app.movestepEditField.Value;
            shiftObject(app,shiftAxis,shiftDistance);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: yyMinusButton
        function yyMinusButtonPushed(app, event)
            shiftAxis = app.manipulatedObject.UserData.ROIyaxis; % get manipulation axis
            shiftDistance = uminus(app.movestepEditField.Value);
            shiftObject(app,shiftAxis,shiftDistance);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: xxMinusButton
        function xxMinusButtonPushed(app, event)
            shiftAxis = app.manipulatedObject.UserData.ROIxaxis; % get manipulation axis
            shiftDistance = uminus(app.movestepEditField.Value);
            shiftObject(app,shiftAxis,shiftDistance);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Value changed function: zzsizeEditField
        function zzsizeEditFieldValueChanged(app, event)
            targetSize = app.zzsizeEditField.Value;
            scaleAxis = app.manipulatedObject.UserData.ROIzaxis;
            currentSize = getVectorLength(app,scaleAxis);
            scaleObject(app,scaleAxis,currentSize,targetSize);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Value changed function: xxsizeEditField
        function xxsizeEditFieldValueChanged(app, event)
            targetSize = app.xxsizeEditField.Value;
            scaleAxis = app.manipulatedObject.UserData.ROIxaxis;
            currentSize = getVectorLength(app,scaleAxis);
            scaleObject(app,scaleAxis,currentSize,targetSize);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Value changed function: yysizeEditField
        function yysizeEditFieldValueChanged(app, event)
            targetSize = app.yysizeEditField.Value;
            scaleAxis = app.manipulatedObject.UserData.ROIyaxis;
            currentSize = getVectorLength(app,scaleAxis);
            scaleObject(app,scaleAxis,currentSize,targetSize);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Value changed function: xlocationEditField
        function xlocationEditFieldValueChanged(app, event)
            newLocation = [app.xlocationEditField.Value, app.ylocationEditField.Value, app.zlocationEditField.Value];
            moveToLocation(app,newLocation);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Value changed function: ylocationEditField
        function ylocationEditFieldValueChanged(app, event)
            newLocation = [app.xlocationEditField.Value, app.ylocationEditField.Value, app.zlocationEditField.Value];
            moveToLocation(app,newLocation);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Value changed function: zlocationEditField
        function zlocationEditFieldValueChanged(app, event)
            newLocation = [app.xlocationEditField.Value, app.ylocationEditField.Value, app.zlocationEditField.Value];
            moveToLocation(app,newLocation);
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: rollPlusButton
        function rollPlusButtonPushed(app, event)
            %object rotation along view axis
            % query camera orientation from object's axes
            [camAzimuth, camElevation] = view(app.manipulatedObject.Parent);
            camAzimuth = camAzimuth - 90; %Matlab measures camera azimuth from the negative y axis for some reason.
            [x,y,z] = sph2cart(deg2rad(camAzimuth),deg2rad(camElevation),1);
            axisAngle = [x,y,z,app.rotstepEditField.Value*pi/180];
            
            rotateObject(app, axisAngle);
            
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: rollMinusButton
        function rollMinusButtonPushed(app, event)
            %object rotation along view axis
            % query camera orientation from object's axes
            [camAzimuth, camElevation] = view(app.manipulatedObject.Parent);
            camAzimuth = camAzimuth - 90; %Matlab measures camera azimuth from the negative y axis for some reason.
            [x,y,z] = sph2cart(deg2rad(camAzimuth),deg2rad(camElevation),1);
            axisAngle = [x,y,z,uminus(app.rotstepEditField.Value*pi/180)];
            
            rotateObject(app, axisAngle);
            
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: pitchPlusButton
        function pitchPlusButtonPushed(app, event)
            %object pitch along view axis
            % query camera orientation from object's axes
            [camAzimuth, camElevation] = view(app.manipulatedObject.Parent);
            camAzimuth = camAzimuth - 90; %Matlab measures camera azimuth from the negative y axis for some reason.
            [x(1),x(2),x(3)] = sph2cart(deg2rad(camAzimuth),deg2rad(camElevation),1);
            y = camup;
            z = cross(x,y);

            axisAngle = [z(1),z(2),z(3),app.rotstepEditField.Value*pi/180];
            
            rotateObject(app, axisAngle);
            
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: pitchMinusButton
        function pitchMinusButtonPushed(app, event)
            %object pitch along view axis
            % query camera orientation from object's axes
            [camAzimuth, camElevation] = view(app.manipulatedObject.Parent);
            camAzimuth = camAzimuth - 90; %Matlab measures camera azimuth from the negative y axis for some reason.
            [x(1),x(2),x(3)] = sph2cart(deg2rad(camAzimuth),deg2rad(camElevation),1);
            y = camup;
            z = cross(x,y);

            axisAngle = [z(1),z(2),z(3),uminus(app.rotstepEditField.Value*pi/180)];
            
            rotateObject(app, axisAngle);
            
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: yawPlusButton
        function yawPlusButtonPushed(app, event)
            %object yaw along view axis
            % query camera orientation from object's axes
            [camAzimuth, camElevation] = view(app.manipulatedObject.Parent);
            camAzimuth = camAzimuth - 90; %Matlab measures camera azimuth from the negative y axis for some reason.
            [x(1),x(2),x(3)] = sph2cart(deg2rad(camAzimuth),deg2rad(camElevation),1);
            y = camup;
            z = cross(x,y);
            y = cross(z,x);

            axisAngle = [y(1),y(2),y(3),uminus(app.rotstepEditField.Value*pi/180)];
            
            rotateObject(app, axisAngle);
            
            evalin('base',app.executeaftermoveEditField.Value);
        end

        % Button pushed function: yawMinusButton
        function yawMinusButtonPushed(app, event)
                        %object yaw along view axis
            % query camera orientation from object's axes
            [camAzimuth, camElevation] = view(app.manipulatedObject.Parent);
            camAzimuth = camAzimuth - 90; %Matlab measures camera azimuth from the negative y axis for some reason.
            [x(1),x(2),x(3)] = sph2cart(deg2rad(camAzimuth),deg2rad(camElevation),1);
            y = camup;
            z = cross(x,y);
            y = cross(z,x);

            axisAngle = [y(1),y(2),y(3),app.rotstepEditField.Value*pi/180];
            
            rotateObject(app, axisAngle);
            
            evalin('base',app.executeaftermoveEditField.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROImanipulationUIFigure = uifigure;
            app.ROImanipulationUIFigure.Name = 'ROI manipulation';
            app.xlocationEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.xlocationEditFieldLabel.Text = 'x location';
            app.xlocationEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.xlocationEditField.ValueChangedFcn = createCallbackFcn(app, @xlocationEditFieldValueChanged, true);
            app.ylocationEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.ylocationEditFieldLabel.Text = 'y location';
            app.ylocationEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.ylocationEditField.ValueChangedFcn = createCallbackFcn(app, @ylocationEditFieldValueChanged, true);
            app.zlocationEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.zlocationEditFieldLabel.Text = 'z location';
            app.zlocationEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.zlocationEditField.ValueChangedFcn = createCallbackFcn(app, @zlocationEditFieldValueChanged, true);
            app.rollPlusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.rollPlusButton.ButtonPushedFcn = createCallbackFcn(app, @rollPlusButtonPushed, true);
            app.rollPlusButton.Text = 'roll +';
            app.rollMinusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.rollMinusButton.ButtonPushedFcn = createCallbackFcn(app, @rollMinusButtonPushed, true);
            app.rollMinusButton.Text = 'roll -';
            app.pitchMinusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.pitchMinusButton.ButtonPushedFcn = createCallbackFcn(app, @pitchMinusButtonPushed, true);
            app.pitchMinusButton.Text = 'pitch -';
            app.pitchPlusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.pitchPlusButton.ButtonPushedFcn = createCallbackFcn(app, @pitchPlusButtonPushed, true);
            app.pitchPlusButton.Text = 'pitch +';
            app.rotstepEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.rotstepEditFieldLabel.Text = 'rot step';
            app.rotstepEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.rotstepEditField.Value = 1;
            app.xxPlusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.xxPlusButton.ButtonPushedFcn = createCallbackFcn(app, @xxPlusButtonPushed, true);
            app.xxPlusButton.Text = 'xx +';
            app.xxMinusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.xxMinusButton.ButtonPushedFcn = createCallbackFcn(app, @xxMinusButtonPushed, true);
            app.xxMinusButton.Text = 'xx -';
            app.yyPlusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.yyPlusButton.ButtonPushedFcn = createCallbackFcn(app, @yyPlusButtonPushed, true);
            app.yyPlusButton.Text = 'yy +';
            app.yyMinusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.yyMinusButton.ButtonPushedFcn = createCallbackFcn(app, @yyMinusButtonPushed, true);
            app.yyMinusButton.Text = 'yy -';
            app.zzPlusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.zzPlusButton.ButtonPushedFcn = createCallbackFcn(app, @zzPlusButtonPushed, true);
            app.zzPlusButton.Text = 'zz +';
            app.zzMinusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.zzMinusButton.ButtonPushedFcn = createCallbackFcn(app, @zzMinusButtonPushed, true);
            app.zzMinusButton.Text = 'zz -';
            app.movestepEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.movestepEditFieldLabel.Text = 'move step';
            app.movestepEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.movestepEditField.Value = 1;
            app.getobjectButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.getobjectButton.ButtonPushedFcn = createCallbackFcn(app, @getobjectButtonPushed, true);
            app.getobjectButton.Text = 'get object';
            app.xxsizeEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.xxsizeEditFieldLabel.Text = 'xx size';
            app.xxsizeEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.xxsizeEditField.ValueChangedFcn = createCallbackFcn(app, @xxsizeEditFieldValueChanged, true);
            app.yysizeEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.yysizeEditFieldLabel.Text = 'yy size';
            app.yysizeEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.yysizeEditField.ValueChangedFcn = createCallbackFcn(app, @yysizeEditFieldValueChanged, true);
            app.zzsizeEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.zzsizeEditFieldLabel.Text = 'zz size';
            app.zzsizeEditField = uieditfield(app.ROImanipulationUIFigure, 'numeric');
            app.zzsizeEditField.ValueChangedFcn = createCallbackFcn(app, @zzsizeEditFieldValueChanged, true);
            app.yawMinusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.yawMinusButton.ButtonPushedFcn = createCallbackFcn(app, @yawMinusButtonPushed, true);
            app.yawMinusButton.Text = 'yaw -';
            app.yawPlusButton = uibutton(app.ROImanipulationUIFigure, 'push');
            app.yawPlusButton.ButtonPushedFcn = createCallbackFcn(app, @yawPlusButtonPushed, true);
            app.yawPlusButton.Text = 'yaw +';
            app.executeaftermoveEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.executeaftermoveEditFieldLabel.Text = 'execute after move';
            app.executeaftermoveEditField = uieditfield(app.ROImanipulationUIFigure, 'text');
            app.objectnameEditFieldLabel = uilabel(app.ROImanipulationUIFigure);
            app.objectnameEditFieldLabel.Text = 'object name';
            app.objectnameEditField = uieditfield(app.ROImanipulationUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = manipulateROI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROImanipulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROImanipulationUIFigure)
        end
    end
end