classdef findIon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        findIonUIFigure   matlab.ui.Figure
        ionsListBoxLabel  matlab.ui.control.Label
        ionsListBox       matlab.ui.control.ListBox
        OKButton          matlab.ui.control.Button
        CancelButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        ionTable % table containing ions and m/c values
        colorScheme % table with ion - color relationships
        spec % mass spectrum plot handle
        peakHeight % height of the selected peak
        ionPlot % plothandle of the ion plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, spec, ionTable, colorScheme, searchRange)
            % allocate input variables
            app.colorScheme = colorScheme;
            app.spec = spec;
          
            % select peak in mass spectrum
            axes(spec.Parent);
            rect = getrect(spec.Parent);
            
            lowerLim = rect(1);
            upperLim = rect(1) + rect(3);
            
            xData = spec.XData(spec.XData > lowerLim & spec.XData < upperLim);
            yData = spec.YData(spec.XData > lowerLim & spec.XData < upperLim);
            
            peakIdx = find(yData == max(yData));
            peakIdx = peakIdx(1); % in case of noisy data, the maximum may occur multiple times
            peakLoc = xData(peakIdx);
            app.peakHeight = yData(peakLoc);
            
            % only consider ions within the search range
            app.ionTable = ionTable(ionTable.mc > peakLoc - searchRange & ionTable.mc < peakLoc + searchRange,:);
            
            % populate listbox
            app.ionsListBox.Items = ionTable.ionName;
            
            % default selection is the ion that is closest to the peak
            
            
            % plot ion in mass spectrum
            
            
            % add additional information in UserData field
            
        end

        % Value changed function: ionsListBox
        function ionsListBoxValueChanged(app, event)
            value = app.ionsListBox.Value;
            disp(num2str(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.findIonUIFigure = uifigure('Visible', 'off');
            app.findIonUIFigure.Name = 'find Ion';
            app.ionsListBoxLabel = uilabel(app.findIonUIFigure);
            app.ionsListBoxLabel.Text = 'ions';
            app.ionsListBox = uilistbox(app.findIonUIFigure);
            app.ionsListBox.ValueChangedFcn = createCallbackFcn(app, @ionsListBoxValueChanged, true);
            app.OKButton = uibutton(app.findIonUIFigure, 'push');
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.findIonUIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.findIonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = findIon(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.findIonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.findIonUIFigure)
        end
    end
end