classdef visibilityControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        visibilityUIFigure  matlab.ui.Figure
    end


    properties (Access = private)
        ax % current axis
        checkBoxes
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ax = gca;
            for disp = 1:length(app.ax.Children)
                app.checkBoxes(disp) = uicheckbox(app.visibilityUIFigure,...
                    'Position', [10 10+disp*20 100 20],...
                    'Text',app.ax.Children(disp).DisplayName);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.visibilityUIFigure = uifigure;
            app.visibilityUIFigure.Name = 'visibility';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visibilityControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.visibilityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.visibilityUIFigure)
        end
    end
end